classdef AdvancedTeachUR3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Q8Slider             matlab.ui.control.Slider
        Q8SliderLabel        matlab.ui.control.Label
        Q7Slider             matlab.ui.control.Slider
        Q7SliderLabel        matlab.ui.control.Label
        YEditField_2         matlab.ui.control.NumericEditField
        YEditField_2Label    matlab.ui.control.Label
        PEditField           matlab.ui.control.NumericEditField
        PEditFieldLabel      matlab.ui.control.Label
        REditField           matlab.ui.control.NumericEditField
        REditFieldLabel      matlab.ui.control.Label
        SetEndEffectorLabel  matlab.ui.control.Label
        MoveJointsLabel      matlab.ui.control.Label
        ZEditField           matlab.ui.control.EditField
        ZEditFieldLabel      matlab.ui.control.Label
        YEditField           matlab.ui.control.EditField
        YEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.EditField
        XEditFieldLabel      matlab.ui.control.Label
        Q6Slider             matlab.ui.control.Slider
        Q6SliderLabel        matlab.ui.control.Label
        Q5Slider             matlab.ui.control.Slider
        Q5SliderLabel        matlab.ui.control.Label
        Q4Slider             matlab.ui.control.Slider
        Q4SliderLabel        matlab.ui.control.Label
        Q3Slider             matlab.ui.control.Slider
        Q3SliderLabel        matlab.ui.control.Label
        Q2Slider             matlab.ui.control.Slider
        Q2SliderLabel        matlab.ui.control.Label
        Q1Slider             matlab.ui.control.Slider
        Q1SliderLabel        matlab.ui.control.Label
        MoveButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        robotModel;
        currentConfiguration = zeros(1,8); % Initial configuration
    end
    
    methods (Access = public)
        
        function initializeRobot(app, robotModel)
            app.robotModel = robotModel;
            app.Q1Slider.Limits = robotModel.model.qlim(1,:);
            app.Q2Slider.Limits = robotModel.model.qlim(2,:);
            app.Q3Slider.Limits = robotModel.model.qlim(3,:);
            app.Q4Slider.Limits = robotModel.model.qlim(4,:);
            app.Q5Slider.Limits = robotModel.model.qlim(5,:);
            app.Q6Slider.Limits = robotModel.model.qlim(6,:);
            app.Q7Slider.Limits = robotModel.model.qlim(7,:);
            app.Q8Slider.Limits = robotModel.model.qlim(8,:);
        end
       
        % Function to update robot configuration based on slider values
        function updateRobotConfiguration(app)
            % Fetch current joint values
            q1 = app.Q1Slider.Value;
            q2 = app.Q2Slider.Value;
            q3 = app.Q3Slider.Value;
            q4 = app.Q4Slider.Value;
            q5 = app.Q5Slider.Value;
            q6 = app.Q6Slider.Value;
            q7 = app.Q7Slider.Value;
            q8 = app.Q8Slider.Value;
    
            % Set new joint configuration
            q = [q1, q2, q3, q4, q5, q6, q7, q8];
            hold on;

            % Update robot visualization
            app.robotModel.model.animate(q);
            app.currentConfiguration = q; % Store the current configuration

            % Compute forward kinematics
            T = app.robotModel.model.fkine(q).T;
        
            % Extract x, y, z values
            position = T(1:3, 4);
            
            % Extract roll, pitch, yaw values
            rpy = tr2rpy(T, 'deg'); % Returns [roll, pitch, yaw] in degrees
        
            % Update the x, y, z text boxes
            app.XEditField.Value = num2str(position(1));
            app.YEditField.Value = num2str(position(2));
            app.ZEditField.Value = num2str(position(3));
            
            % Update the r, p, y text boxes
            app.REditField.Value = rpy(1);
            app.PEditField.Value = rpy(2);
            app.YEditField_2.Value = rpy(3); % Assuming YEditField_2 is for yaw (y)

        
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
        updateRobotConfiguration(app);
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
        updateRobotConfiguration(app);
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
        updateRobotConfiguration(app);
        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(app, event)
        updateRobotConfiguration(app);
        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(app, event)
        updateRobotConfiguration(app);
        end

        % Value changed function: Q6Slider
        function Q6SliderValueChanged(app, event)
        updateRobotConfiguration(app);
        end

        % Value changed function: Q7Slider
        function Q7SliderValueChanged(app, event)
        updateRobotConfiguration(app);
            
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            % Read in the users end-effector position preference
            x = str2double(app.XEditField.Value);
            y = str2double(app.YEditField.Value);
            z = str2double(app.ZEditField.Value);
            targetPosition = transl(x, y, z);

            % Setup q configs for ikine
            q_initial = zeros(1, 8); % Initial guess for the IK function
            currentq = app.currentConfiguration;
            q_solution = app.robotModel.model.ikcon(targetPosition, q_initial);
            
            if ~any(isnan(q_solution))               
                qPath1 = jtraj(currentq, q_solution, 100); % 50 steps
                for i = 1:size(qPath1, 1)
                    app.robotModel.model.animate(qPath1(i, :));
                    drawnow();
                end
            else
                disp('Invalid coordinates, try again.');
            end
        end

        % Value changed function: Q8Slider
        function Q8SliderValueChanged(app, event)
            updateRobotConfiguration(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4SliderValueChanged, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5SliderValueChanged, true);
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.ValueChangedFcn = createCallbackFcn(app, @Q6SliderValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.MoveJointsLabel = uilabel(app.UIFigure);
            app.MoveJointsLabel.Text = 'Move Joints';
            app.SetEndEffectorLabel = uilabel(app.UIFigure);
            app.SetEndEffectorLabel.Text = 'Set End-Effector';
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q7SliderLabel = uilabel(app.UIFigure);
            app.Q7SliderLabel.Text = 'Q7';
            app.Q7Slider = uislider(app.UIFigure);
            app.Q7Slider.ValueChangedFcn = createCallbackFcn(app, @Q7SliderValueChanged, true);
            app.Q8SliderLabel = uilabel(app.UIFigure);
            app.Q8SliderLabel.Text = 'Q8';
            app.Q8Slider = uislider(app.UIFigure);
            app.Q8Slider.ValueChangedFcn = createCallbackFcn(app, @Q8SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdvancedTeachUR3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end